classdef FittingOptionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingOptionsUIFigure     matlab.ui.Figure
        UpdateButton               matlab.ui.control.Button
        SharedParametersLabel      matlab.ui.control.Label
        SkewnessparameterCheckBox  matlab.ui.control.CheckBox
        ShapeparameterCheckBox     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        GelBoxApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.GelBoxApp = caller;
            app.ShapeparameterCheckBox.Value = app.GelBoxApp.fitting_options.shared_shape;
            app.SkewnessparameterCheckBox.Value = app.GelBoxApp.fitting_options.shared_skewness;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            shape = app.ShapeparameterCheckBox.Value;
            skewness = app.SkewnessparameterCheckBox.Value;
            UpdateFittingOptions(app.GelBoxApp,shape,skewness);
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingOptionsUIFigure = uifigure('Visible', 'off');
            app.FittingOptionsUIFigure.Name = 'Fitting Options';
            app.ShapeparameterCheckBox = uicheckbox(app.FittingOptionsUIFigure);
            app.ShapeparameterCheckBox.Text = 'Shape parameter';
            app.ShapeparameterCheckBox.Value = true;
            app.SkewnessparameterCheckBox = uicheckbox(app.FittingOptionsUIFigure);
            app.SkewnessparameterCheckBox.Text = 'Skewness parameter';
            app.SkewnessparameterCheckBox.Value = true;
            app.SharedParametersLabel = uilabel(app.FittingOptionsUIFigure);
            app.SharedParametersLabel.Text = 'Shared Parameters';
            app.UpdateButton = uibutton(app.FittingOptionsUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.FittingOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingOptionsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingOptionsUIFigure)
        end
    end
end